classdef NoiseGenGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NoiseGen                       matlab.ui.Figure
        NoiseGenLabel                  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        NoiseTypeandStdDevLabel        matlab.ui.control.Label
        WhiteEditFieldLabel            matlab.ui.control.Label
        WhiteEditField                 matlab.ui.control.NumericEditField
        BaselineOffsetEditFieldLabel   matlab.ui.control.Label
        BaselineOffsetEditField        matlab.ui.control.NumericEditField
        fEditFieldLabel                matlab.ui.control.Label
        fEditField                     matlab.ui.control.NumericEditField
        ShotEditFieldLabel             matlab.ui.control.Label
        ShotEditField                  matlab.ui.control.NumericEditField
        MultiplicativeOffsetEditFieldLabel  matlab.ui.control.Label
        MultiplicativeOffsetEditField  matlab.ui.control.NumericEditField
        Proportional1fEditFieldLabel   matlab.ui.control.Label
        Proportional1fEditField        matlab.ui.control.NumericEditField
        GenerateButton                 matlab.ui.control.Button
        GAMMA1Label                    matlab.ui.control.Label
        gamma_1EditField               matlab.ui.control.NumericEditField
        GAMMA2EditFieldLabel           matlab.ui.control.Label
        GAMMA2EditField                matlab.ui.control.NumericEditField
        ReferenceSignalforproportionalnoiseDropDownLabel  matlab.ui.control.Label
        ReferenceSignalforproportionalnoiseDropDown  matlab.ui.control.DropDown
        Lamp                           matlab.ui.control.Lamp
        ResetButton_2                  matlab.ui.control.Button
        alpha                          matlab.ui.control.NumericEditField
        alphatext                      matlab.ui.control.Label
        NoiseReplicatesxSignalLengthEditFieldLabel  matlab.ui.control.Label
        NoiseReplicatesxSignalLengthEditField  matlab.ui.control.EditField
        ECMEditFieldLabel              matlab.ui.control.Label
        ECMEditField                   matlab.ui.control.EditField
        TheoreticalECMEditFieldLabel   matlab.ui.control.Label
        TheoreticalECMEditField        matlab.ui.control.EditField
        StoreOutputVariablesLabel      matlab.ui.control.Label
        alpha_2                        matlab.ui.control.NumericEditField
        alphatext_2                    matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        NoisePreviewTab                matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ErrorCovarianceMatrixTab       matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        MeshButton                     matlab.ui.control.Button
        MeshButton_2                   matlab.ui.control.Button
        ReplicatesEditFieldLabel       matlab.ui.control.Label
        ReplicatesEditField            matlab.ui.control.NumericEditField
        SignalLengthEditFieldLabel     matlab.ui.control.Label
        SignalLengthEditField          matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
    end

    
    properties (Access = public)
        G % Tracking if generation has occured (for reset)
    end
    
    methods (Access = public)
        
        function [ECMout]=fgen(app,chan,a,p,rho)
            w=round(chan/2);
            m=rho*2*w+1; % Filter width
            f=[1/(3*m) 1/(2*w):1/(2*w):0.5]; % Frequency vector
            H=abs(1./(f.^(a/2)));
            H=[H fliplr(H(2:end))]; % Mask
            Z=fft(H);
            c=sqrt(real(Z).^2+imag(Z).^2); % Coefficients
            c=[c(w+1:end) c(1:w)];
            win=0.54-0.46*cos(2*pi*[1:length(c)]/length(c)); % Hamming window
            c=c.*win;
            c=c/norm(c); % Normalize
            
            % ECM via filter matrix
            npts=chan+2*w;
            F=zeros(npts,chan);
            for i=1:chan
                indx=i+2*w;
                F(i:indx,i)=c';
            end
            ECMout=p^2*(F'*F); % Construct ECM with filter
            
            % % ECM via self convolution of c (might be slightly slower, but don't have to store F)
            % ECMout=zeros(chan,chan);
            % sc2=conv(c,c);
            % for i = 1:chan
            %     k=1;
            %     for j = i:chan
            %         ECMout(i,j)=sc2(k+m);
            %         k=k+1;
            %     end
            % end
            % ECMout=triu(ECMout)+triu(ECMout,1)';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.G = 0;
            app.ReferenceSignalforproportionalnoiseDropDown.Items = vars;
            app.ReferenceSignalforproportionalnoiseDropDown.Visible = 'off';
            app.ReferenceSignalforproportionalnoiseDropDownLabel.Visible = 'off';
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.G = 1;
            app.Lamp.Color = 'red';
            app.GenerateButton.Text = 'Working!';
            drawnow
            fcov = 0;
            fcovp = 0;
            GAMMA1 = app.gamma_1EditField.Value;
            GAMMA2 = app.GAMMA2EditField.Value;
            a=app.alpha.Value;
            reps = app.ReplicatesEditField.Value;
            chan = app.SignalLengthEditField.Value;
            Xbar=zeros(1,chan);
            rho=app.alpha_2.Value;
            std = [app.BaselineOffsetEditField.Value, app.MultiplicativeOffsetEditField.Value, app.WhiteEditField.Value, app.ShotEditField.Value, app.fEditField.Value, app.Proportional1fEditField.Value];
            catch1 = 0;
            if  ~isnan(str2double(app.NoiseReplicatesxSignalLengthEditField.Value))==1 || ~isnan(str2double(app.ECMEditField.Value))==1 || ~isnan(str2double(app.TheoreticalECMEditField.Value))==1
                uialert(app.NoiseGen,'Cannot assignnumber as variable ','Error');
                catch1 = 1;
            end
            if std(5) > 0 || std(6) > 0 % Pre-gen fnoise matrix
                
            end
            %
            % Input check
            %
            if sum(std) > 0 % Proceed to model if user inputs at least one std
                propC = [std(2), std(4), std(6)]; % Collect proportional terms for check
                if sum(propC) > 0 % Proceed if at least one proportional std and signal are specified
                    XbarT = app.ReferenceSignalforproportionalnoiseDropDown.Value;
                    Xbar = evalin('base',XbarT);
                    if isvector(Xbar) == 1
                        if isrow(Xbar) == 0 % Flip input signal if column vector
                            Xbar = Xbar';
                        end
                    else
                        uialert(app.NoiseGen,'Reference signal should be 1x(signal length).','Error');
                        app.Lamp.Color = 'green';
                        app.GenerateButton.Text = 'Generate';
                        catch1 = 1;
                    end
                    if chan~=length(Xbar) % Make sure desired signal length equals input signal
                        uialert(app.NoiseGen,'Requested channel length is not equal to the length of the input signal.','Error');
                        app.Lamp.Color = 'green';
                        app.GenerateButton.Text = 'Generate';
                        Xbar=-1*ones(1,chan);
                        catch1 = 1;
                    end
                    if std(5) > 0 || std(6) > 0 % Pre-gen 1/f^\alpha matrix
                        [fcovt]=fgen(app,chan,a,1,rho);
                        if std(6) > 0 % Do normalized proportional pink noise calculation to form fcov matrix
                            fcovp = fcovt.*(Xbar'*Xbar);
                        end
                        if std(5) > 0 % Pink w/ other prop components
                            fcov=fcovt;
                        end
                    end
                elseif sum(propC) == 0 && any(Xbar) == 1 % Defined input signal, but result does not depend on it
                    uialert(app.NoiseGen,'Input signal detected, but no proportional noise std. dev. specified.','Error');
                    app.Lamp.Color = 'green';
                    app.GenerateButton.Text = 'Generate';
                elseif std(5) > 0 % Catch pink w/ no other proportional components
                    [fcovt]=fgen(app,chan,a,1,rho);
                    fcov=fcovt;
                elseif any(Xbar) == 0 && sum(propC) > 0 % Defined prop. error. type, but no input signal defined
                    uialert(app.NoiseGen,'Proportional noise std. dev. specified in input, but no input signal was detected.','Error');
                    app.Lamp.Color = 'green';
                    app.GenerateButton.Text = 'Generate';
                end
                Xbar=Xbar';
                %
                % Generate theoretical covariance matrix
                %
                if catch1 == 0
                    COVM= [ones(chan,1),Xbar.^(GAMMA2)]*[std(1)^2,0;0,std(2)^2]*[ones(chan,1),Xbar.^(GAMMA2)]' + std(3)^2*diag(ones(1,chan))...
                        + std(4)^2*diag(Xbar.^GAMMA1) + std(5)^2*fcov + fcovp*std(6)^2;
                    %
                    % Generate noise sequences from theoretical covariance matrix
                    %
                    [~,S,V] = svd(COVM);
                    for i = 1:reps
                        eiid = randn(1,chan);
                        esig(i,:) = eiid*sqrt(S)*V';
                    end
                    X=esig;
                    COV=(esig'*esig)/(reps-1);
                    if reps < 11
                        plot(app.UIAxes,X');
                    else
                        Xtemp = X(1:10,:); % Store subsection of X for plot (bug in appdesigner??)
                        plot(app.UIAxes,Xtemp');
                    end
                    imagesc(app.UIAxes2,COV);
                    imagesc(app.UIAxes2_2,COVM);
                    app.UIAxes2_2.XLim=[0 chan];
                    app.UIAxes2_2.YLim=[0 chan];
                    app.UIAxes2.XLim=[0 chan];
                    app.UIAxes2.YLim=[0 chan];
                    app.UIAxes2.XTick=[0 floor(chan/2) chan];
                    app.UIAxes2.YTick=[0 floor(chan/2) chan];
                    app.UIAxes2.XTickLabel=[0 floor(chan/2) chan];
                    app.UIAxes2.YTickLabel=[0 floor(chan/2) chan];
                    app.UIAxes2_2.XTick=[0 floor(chan/2) chan];
                    app.UIAxes2_2.YTick=[0 floor(chan/2) chan];
                    app.UIAxes2_2.XTickLabel=[0 floor(chan/2) chan];
                    app.UIAxes2_2.YTickLabel=[0 floor(chan/2) chan];
                    assignin('base', app.ECMEditField.Value, COV);
                    assignin('base', app.TheoreticalECMEditField.Value, COVM);
                    assignin('base', app.NoiseReplicatesxSignalLengthEditField.Value, X);
                end
                app.Lamp.Color = 'green';
                app.GenerateButton.Text = 'Generate';
            else
                uialert(app.NoiseGen,'Please input at least one noise component standard deviation.','Error');
                app.Lamp.Color = 'green';
                app.GenerateButton.Text = 'Generate';
            end
            
        end

        % Button pushed function: Button
        function InfoButtonPushed(app, event)
            % Create UIFigure
            UIFigure = uifigure;
            UIFigure.Color = [0 0 1];
            UIFigure.Position = [100 100 640 480];
            UIFigure.Name = 'UI Figure';
            UIFigure.Resize = 'off';
            
            % Create NoiseGenVersion10InformationPanel
            NoiseGenVersion10InformationPanel = uipanel(UIFigure);
            NoiseGenVersion10InformationPanel.TitlePosition = 'centertop';
            NoiseGenVersion10InformationPanel.Title = 'NoiseGen Version 1.0 Information';
            NoiseGenVersion10InformationPanel.FontWeight = 'bold';
            NoiseGenVersion10InformationPanel.FontSize = 20;
            NoiseGenVersion10InformationPanel.Position = [1 20 640 443];
            
            % Create Label
            Label = uilabel(NoiseGenVersion10InformationPanel);
            Label.HorizontalAlignment = 'right';
            Label.Position = [-22 390 25 15];
            Label.Text = '';
            
            % Create TextArea
            TextArea = uitextarea(NoiseGenVersion10InformationPanel);
            TextArea.Editable = 'off';
            TextArea.FontSize = 16;
            TextArea.Position = [18 9 600 398];
            TextArea.Value = {'NoiseGenGUI is a tool that was developed to aid the simulation of common measurement noise types in chemical data. '; ''; 'More information and help documentation can be found here:'; ''; ''; 'Author: Steve Driscoll'; 'Trace Analysis Research Centre, Department of Chemistry,'; 'Dalhousie University, Halifax, Nova Scotia, Canada B3H 4J3'; 'Email: Stephen.Driscoll@dal.ca '; 'Website:  http://groupwentzell.chemistry.dal.ca/'; 'April 2017;Last revision: April 2018'};
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            COV = evalin('base', app.ECMEditField.Value);
            figure();
            mesh(COV);
        end

        % Button pushed function: MeshButton_2
        function MeshButton_2Pushed(app, event)
            COVM = evalin('base', app.TheoreticalECMEditField.Value);
            figure();
            mesh(COVM);
        end

        % Value changed function: MultiplicativeOffsetEditField
        function MultiplicativeOffsetEditFieldValueChanged(app, event)
            value = app.MultiplicativeOffsetEditField.Value;
            if value ~= 0
                app.ReferenceSignalforproportionalnoiseDropDown.Visible = 'on';
                app.ReferenceSignalforproportionalnoiseDropDown.Enable = 'on';
                app.ReferenceSignalforproportionalnoiseDropDownLabel.Visible = 'on';
                vars = evalin('base','who');
            end
        end

        % Value changed function: Proportional1fEditField
        function Proportional1fEditFieldValueChanged(app, event)
            value = app.Proportional1fEditField.Value;
            if value ~= 0
                app.ReferenceSignalforproportionalnoiseDropDown.Visible = 'on';
                app.ReferenceSignalforproportionalnoiseDropDown.Enable = 'on';
                app.ReferenceSignalforproportionalnoiseDropDownLabel.Visible = 'on';
                vars = evalin('base','who');
            end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            vars = evalin('base','who');
            app.ReferenceSignalforproportionalnoiseDropDown.Items = vars;
            app.ReferenceSignalforproportionalnoiseDropDown.Visible = 'off';
            app.ReferenceSignalforproportionalnoiseDropDownLabel.Visible = 'off';
            app.WhiteEditField.Value = 0;
            app.BaselineOffsetEditField.Value = 0;
            app.fEditField.Value = 0;
            app.ShotEditField.Value = 0;
            app.MultiplicativeOffsetEditField.Value = 0;
            app.Proportional1fEditField.Value = 0;
            app.gamma_1EditField.Value = 0.5;
            app.GAMMA2EditField.Value = 1;
            app.alpha.Value = 1;
            app.alpha_2.Value = 1;
            if app.G == 1
                children(1,:) = get(app.UIAxes, 'children');
                children(2,:) = get(app.UIAxes2,'children');
                children(3,:) = get(app.UIAxes2_2,'children');
                delete(children(:));
            end
            app.G = 0;
        end

        % Value changed function: ShotEditField
        function ShotEditFieldValueChanged(app, event)
            value = app.ShotEditField.Value;
            if value ~= 0
                app.ReferenceSignalforproportionalnoiseDropDown.Visible = 'on';
                app.ReferenceSignalforproportionalnoiseDropDown.Enable = 'on';
                app.ReferenceSignalforproportionalnoiseDropDownLabel.Visible = 'on';
                vars = evalin('base','who');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NoiseGen = uifigure;
            app.NoiseGen.Name = 'NoiseGen';
            app.NoiseGenLabel = uilabel(app.NoiseGen);
            app.NoiseGenLabel.Text = 'NoiseGen';
            app.Panel = uipanel(app.NoiseGen);
            app.NoiseTypeandStdDevLabel = uilabel(app.Panel);
            app.NoiseTypeandStdDevLabel.Text = 'Noise Type and Std. Dev.';
            app.WhiteEditFieldLabel = uilabel(app.Panel);
            app.WhiteEditFieldLabel.Text = 'White';
            app.WhiteEditField = uieditfield(app.Panel, 'numeric');
            app.WhiteEditField.Limits = [0 Inf];
            app.BaselineOffsetEditFieldLabel = uilabel(app.Panel);
            app.BaselineOffsetEditFieldLabel.Text = 'Baseline Offset';
            app.BaselineOffsetEditField = uieditfield(app.Panel, 'numeric');
            app.BaselineOffsetEditField.Limits = [0 Inf];
            app.fEditFieldLabel = uilabel(app.Panel);
            app.fEditFieldLabel.Text = '1/f^α';
            app.fEditField = uieditfield(app.Panel, 'numeric');
            app.fEditField.Limits = [0 Inf];
            app.ShotEditFieldLabel = uilabel(app.Panel);
            app.ShotEditFieldLabel.Text = 'Shot';
            app.ShotEditField = uieditfield(app.Panel, 'numeric');
            app.ShotEditField.Limits = [0 Inf];
            app.ShotEditField.ValueChangedFcn = createCallbackFcn(app, @ShotEditFieldValueChanged, true);
            app.MultiplicativeOffsetEditFieldLabel = uilabel(app.Panel);
            app.MultiplicativeOffsetEditFieldLabel.Text = 'Multiplicative Offset';
            app.MultiplicativeOffsetEditField = uieditfield(app.Panel, 'numeric');
            app.MultiplicativeOffsetEditField.Limits = [0 Inf];
            app.MultiplicativeOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @MultiplicativeOffsetEditFieldValueChanged, true);
            app.Proportional1fEditFieldLabel = uilabel(app.Panel);
            app.Proportional1fEditFieldLabel.Text = 'Proportional 1/f^α';
            app.Proportional1fEditField = uieditfield(app.Panel, 'numeric');
            app.Proportional1fEditField.Limits = [0 Inf];
            app.Proportional1fEditField.ValueChangedFcn = createCallbackFcn(app, @Proportional1fEditFieldValueChanged, true);
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.GAMMA1Label = uilabel(app.Panel);
            app.GAMMA1Label.Text = 'GAMMA1';
            app.gamma_1EditField = uieditfield(app.Panel, 'numeric');
            app.gamma_1EditField.Limits = [0 Inf];
            app.gamma_1EditField.Value = 0.5;
            app.GAMMA2EditFieldLabel = uilabel(app.Panel);
            app.GAMMA2EditFieldLabel.Text = 'GAMMA2';
            app.GAMMA2EditField = uieditfield(app.Panel, 'numeric');
            app.GAMMA2EditField.Limits = [0 Inf];
            app.GAMMA2EditField.Value = 1;
            app.ReferenceSignalforproportionalnoiseDropDownLabel = uilabel(app.Panel);
            app.ReferenceSignalforproportionalnoiseDropDownLabel.Text = 'Reference Signal (for proportional noise)';
            app.ReferenceSignalforproportionalnoiseDropDown = uidropdown(app.Panel);
            app.ReferenceSignalforproportionalnoiseDropDown.Items = {'none'};
            app.ReferenceSignalforproportionalnoiseDropDown.Enable = 'off';
            app.ReferenceSignalforproportionalnoiseDropDown.Value = 'none';
            app.Lamp = uilamp(app.Panel);
            app.ResetButton_2 = uibutton(app.Panel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.alpha = uieditfield(app.Panel, 'numeric');
            app.alpha.Limits = [0 2];
            app.alpha.Value = 1;
            app.alphatext = uilabel(app.Panel);
            app.alphatext.Text = 'α';
            app.NoiseReplicatesxSignalLengthEditFieldLabel = uilabel(app.Panel);
            app.NoiseReplicatesxSignalLengthEditFieldLabel.Text = 'Noise (Replicates x Signal Length)';
            app.NoiseReplicatesxSignalLengthEditField = uieditfield(app.Panel, 'text');
            app.NoiseReplicatesxSignalLengthEditField.Value = 'X';
            app.ECMEditFieldLabel = uilabel(app.Panel);
            app.ECMEditFieldLabel.Text = 'ECM';
            app.ECMEditField = uieditfield(app.Panel, 'text');
            app.ECMEditField.Value = 'COV';
            app.TheoreticalECMEditFieldLabel = uilabel(app.Panel);
            app.TheoreticalECMEditFieldLabel.Text = 'Theoretical ECM';
            app.TheoreticalECMEditField = uieditfield(app.Panel, 'text');
            app.TheoreticalECMEditField.Value = 'COVM';
            app.StoreOutputVariablesLabel = uilabel(app.Panel);
            app.StoreOutputVariablesLabel.Text = 'Store Output Variables';
            app.alpha_2 = uieditfield(app.Panel, 'numeric');
            app.alpha_2.Limits = [1 Inf];
            app.alpha_2.Value = 1;
            app.alphatext_2 = uilabel(app.Panel);
            app.alphatext_2.Text = 'ρ';
            app.TabGroup = uitabgroup(app.NoiseGen);
            app.NoisePreviewTab = uitab(app.TabGroup);
            app.NoisePreviewTab.Title = 'Noise Preview';
            app.UIAxes = uiaxes(app.NoisePreviewTab);
            title(app.UIAxes, 'Noise Signals')
            xlabel(app.UIAxes, 'Channel')
            ylabel(app.UIAxes, 'Intensity')
            app.ErrorCovarianceMatrixTab = uitab(app.TabGroup);
            app.ErrorCovarianceMatrixTab.Title = 'Error Covariance Matrix';
            app.UIAxes2 = uiaxes(app.ErrorCovarianceMatrixTab);
            title(app.UIAxes2, 'ECM')
            xlabel(app.UIAxes2, 'Channel')
            ylabel(app.UIAxes2, 'Channel')
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.XTick = 0;
            app.UIAxes2.YTick = 0;
            app.UIAxes2_2 = uiaxes(app.ErrorCovarianceMatrixTab);
            title(app.UIAxes2_2, 'Theoretical ECM')
            xlabel(app.UIAxes2_2, 'Channel')
            ylabel(app.UIAxes2_2, 'Channel')
            app.UIAxes2_2.XTick = 0;
            app.UIAxes2_2.YTick = 0;
            app.MeshButton = uibutton(app.ErrorCovarianceMatrixTab, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Text = 'Mesh';
            app.MeshButton_2 = uibutton(app.ErrorCovarianceMatrixTab, 'push');
            app.MeshButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeshButton_2Pushed, true);
            app.MeshButton_2.Text = 'Mesh';
            app.ReplicatesEditFieldLabel = uilabel(app.NoiseGen);
            app.ReplicatesEditFieldLabel.Text = 'Replicates';
            app.ReplicatesEditField = uieditfield(app.NoiseGen, 'numeric');
            app.ReplicatesEditField.Limits = [2 1000];
            app.ReplicatesEditField.Value = 2;
            app.SignalLengthEditFieldLabel = uilabel(app.NoiseGen);
            app.SignalLengthEditFieldLabel.Text = 'Signal Length';
            app.SignalLengthEditField = uieditfield(app.NoiseGen, 'numeric');
            app.SignalLengthEditField.Limits = [5 10000];
            app.SignalLengthEditField.Value = 5;
            app.Button = uibutton(app.NoiseGen, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.Button.Text = '?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NoiseGenGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NoiseGen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NoiseGen)
        end
    end
end